classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TextArea_3       matlab.ui.control.TextArea
        operationsLabel  matlab.ui.control.Label
        TextArea_2       matlab.ui.control.TextArea
        EditField_10     matlab.ui.control.EditField
        DecreasseButton  matlab.ui.control.Button
        IncreaseButton   matlab.ui.control.Button
        EditField_8      matlab.ui.control.EditField
        EditField_7      matlab.ui.control.EditField
        SaveButton_2     matlab.ui.control.Button
        Gauge            matlab.ui.control.Gauge
        DropDown         matlab.ui.control.DropDown
        Slider           matlab.ui.control.Slider
        EditField_3      matlab.ui.control.EditField
        EditField        matlab.ui.control.EditField
        Button_3         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        TextArea         matlab.ui.control.TextArea
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
        ContextMenu      matlab.ui.container.ContextMenu
    end

    
    properties (Access = public)
        Property % Description
        % Properties for storing images
        inputImage   % Stores the input image
        outputImage  % Stores the processed (output) imag 
        Svalue
        noisyImge
        
    end
    
    methods (Access = private)
        
      function updateOutputImage(app, operation)
        % Implement the logic for updating the output image
        switch operation
             case 'Gaussian noise'
            app.outputImage = imnoise(app.inputImage, 'gaussian');
        case 'Salt and pepper noise'
            app.outputImage = imnoise(app.inputImage, 'salt & pepper');
            
       
        case 'Complement'
            app.outputImage = imcomplement(app.inputImage); % Image complement
        case 'Equlaization'
            app.outputImage = histeq(app.inputImage); % Histogram equalization
            case 'Brightness'
                app.Slider.Limits=[40 255];
             app.outputImage=app.inputImage+app.Slider.Value;
            case 'Darkness'
                 app.Slider.Limits=[40 255];
             app.outputImage=app.inputImage-app.Slider.Value;
             
      
            case 'FFT'
    % Convert to grayscale if the input is a color image
    if size(app.inputImage, 3) == 3
        grayImage = rgb2gray(app.inputImage);
    else
        grayImage = app.inputImage;
    end

    % Apply FFT
    fftImage = fft2(double(grayImage));
    fftImageShifted = log(1 + abs(fftImage));

    % Normalize for display
    app.outputImage = mat2gray(fftImageShifted);
       
            
case 'FFTShift'
    % Convert to grayscale if the input is a color image
    if size(app.inputImage, 3) == 3
        grayImage = rgb2gray(app.inputImage);
    else
        grayImage = app.inputImage;
    end

    % Apply FFT and then fftshift for visualization
    fftImage = fft2(double(grayImage));
    fftImageShifted = fftshift(log(1 + abs(fftImage)));

    % Normalize for display
    app.outputImage = mat2gray(fftImageShifted);
     
case 'Remove noise'
    % Check if the image is color and convert to grayscale if necessary
    if size(app.inputImage, 3) == 3
        grayImage = rgb2gray(app.inputImage);
    else
        grayImage = app.inputImage;
    end

    % Apply a median filter
    app.outputImage = medfilt2(grayImage, [3 3]);

            case 'IFFT'
                % Apply IFFT - assuming the inputImage is an unshifted frequency spectrum
                ifftImage = ifft2(app.inputImage);
                app.outputImage = abs(ifftImage); % Get the real part  


   end

        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SaveButtonPushed(app, event)
         % Check if the processed image exists
        if isempty(app.outputImage)
        uialert(app.UIFigure, 'No processed image available to save.', 'Save Error');
              return;
        end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

   [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select an image');
    if isequal(file, 0)
        return;
    end
    app.inputImage = imread(fullfile(path, file));
    imshow(app.inputImage, 'Parent', app.UIAxes);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
    Operation = app.DropDown.Value;
   updateOutputImage(app, Operation);
    imshow(app.outputImage, 'Parent', app.UIAxes2); % Update display
% Debugging: Check if images are valid
    disp('Displaying images...');
    disp(['Size of input image: ', mat2str(size(app.inputImage))]);
    disp(['Size of output image: ', mat2str(size(app.outputImage))]);

    % Display images
    if ~isempty(app.inputImage)
        imshow(app.inputImage, 'Parent', app.UIAxes);  % Original image
    end
    if ~isempty(app.outputImage)
        imshow(app.outputImage, 'Parent', app.UIAxes2);  % Processed image
    end
            % Determine the operation and execute corresponding code
            switch event
                 case 'Gaussian noise'
            app.outputImage = imnoise(app.inputImage, 'gaussian');
        case 'Salt and pepper noise'
            app.outputImage = imnoise(app.inputImage, 'salt & pepper');
        case 'Equlaization'
            app.outputImage = histeq(app.inputImage); % Histogram equalizationa
        case 'Brightness'
             app.Slider.Limits=[40 255];
             app.outputImage=app.inputImage+app.Slider.Value;
                case 'Darkness'
                 app.Slider.Limits=[40 255];
             app.outputImage=app.inputImage-app.Slider.Value;


                  case 'FFT'
    % Convert to grayscale if the input is a color image
    if size(app.inputImage, 3) == 3
        grayImage = rgb2gray(app.inputImage);
    else
        grayImage = app.inputImage;
    end

    % Apply FFT
    fftImage = fft2(double(grayImage));
    fftImageShifted = log(1 + abs(fftImage));

    % Normalize for display
    app.outputImage = mat2gray(fftImageShifted);
           
   
case 'FFTShift'
    % Convert to grayscale if the input is a color image
    if size(app.inputImage, 3) == 3
        grayImage = rgb2gray(app.inputImage);
    else
        grayImage = app.inputImage;
    end

    % Apply FFT and then fftshift for visualization
    fftImage = fft2(double(grayImage));
    fftImageShifted = fftshift(log(1 + abs(fftImage)));

    % Normalize for display
    app.outputImage = mat2gray(fftImageShifted);
                case 'Remove noise'
    % Check if the image is color and convert to grayscale if necessary
    if size(app.inputImage, 3) == 3
        grayImage = rgb2gray(app.inputImage);
    else
        grayImage = app.inputImage;
    end

    % Apply a median filter
    app.outputImage = medfilt2(grayImage, [3 3]);


            case 'IFFT'
                % Apply IFFT - assuming the inputImage is an unshifted frequency spectrum
                ifftImage = ifft2(app.inputImage);
                app.outputImage = abs(ifftImage); % Get the real part

                  
            end

            % Update the display or UI based on the processed image
            imshow(app.outputImage, 'Parent', app.UIAxes2);
       
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIFigure);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
% Convert the string to a numeric value
    numericValue = str2double(app.EditField_8.Value);

    % Check if the conversion was successful and the value is within the gauge's range
    if isnan(numericValue) || ~isfinite(numericValue)
        % Handle invalid input, perhaps by showing a message to the user
        uialert(app.UIFigure, 'Please enter a valid number.', 'Invalid Input');
    else
        % Set the gauge value
        app.Gauge.Value = numericValue;
    end
        end

        % Button pushed function: IncreaseButton
        function IncreaseButtonPushed(app, event)
            app.Gauge.Value=app.Gauge.Value+5;
        end

        % Button pushed function: DecreasseButton
        function DecreasseButtonPushed(app, event)
             app.Gauge.Value=app.Gauge.Value-5;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Upload your image'};
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'upload.png');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'exit.png');
            app.Button_3.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Save you work';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = ' original image';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Gaussian noise', 'Salt and pepper noise', 'Remove noise', ' Equlaization', 'Brightness', 'Darkness', 'FFT', 'FFTShift', 'IFFT', '', '', '', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.SaveButton_2 = uibutton(app.UIFigure, 'push');
            app.SaveButton_2.Icon = fullfile(pathToMLAPP, 'bookmark-file.png');
            app.SaveButton_2.Text = '';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_7.Value = 'processed image';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = '89';
            app.IncreaseButton = uibutton(app.UIFigure, 'push');
            app.IncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseButtonPushed, true);
            app.IncreaseButton.Text = 'Increase';
            app.DecreasseButton = uibutton(app.UIFigure, 'push');
            app.DecreasseButton.ButtonPushedFcn = createCallbackFcn(app, @DecreasseButtonPushed, true);
            app.DecreasseButton.Text = 'Decreasse';
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EditField_10.Value = 'I did it! Teamwork makes the dream work';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Add a touch of visual appeal and interactivity to your MATLAB applications with intuitive sliders and Guage'};
            app.operationsLabel = uilabel(app.UIFigure);
            app.operationsLabel.Text = ' operations';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Value = {'Magic Brush'; 'Beyond filters, beyond edits, weaves photo magic into every touch.'};
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end